classdef SeparationDistanceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BoundaryDistanceCalculatorLabel  matlab.ui.control.Label
        RadiationIntensityButtonGroup  matlab.ui.container.ButtonGroup
        kWm2Label                      matlab.ui.control.Label
        kWm2Label_2                    matlab.ui.control.Label
        NormalButton                   matlab.ui.control.Button
        ReducedButton                  matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        BoundaryDistanceTab            matlab.ui.container.Tab
        SprinklerCheckbox              matlab.ui.control.CheckBox
        SprinklerSuppressionSystemLabel  matlab.ui.control.Label
        EnterpercentageofunprotectedareaEditFieldLabel  matlab.ui.control.Label
        Percentage                     matlab.ui.control.NumericEditField
        EnterwidthEditFieldLabel       matlab.ui.control.Label
        EnterwidthEditField            matlab.ui.control.NumericEditField
        EnterHeightLabel               matlab.ui.control.Label
        EnterHeightEditField           matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        SeparationDistanceLabel        matlab.ui.control.Label
        BoundaryDistanceLabel_2        matlab.ui.control.Label
        mLabel_4                       matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        HeightTab                      matlab.ui.container.Tab
        CalculateButtonHeight          matlab.ui.control.Button
        HeightLabel                    matlab.ui.control.Label
        mLabel_5                       matlab.ui.control.Label
        EnterpercentageofunprotectedareaEditFieldLabel_2  matlab.ui.control.Label
        PercentageHeight               matlab.ui.control.NumericEditField
        EnterwidthEditField_2Label     matlab.ui.control.Label
        EnterwidthEditFieldHeight      matlab.ui.control.NumericEditField
        EnterseparationdistanceEditFieldLabel  matlab.ui.control.Label
        EnterseparationdistanceEditFieldHeight  matlab.ui.control.NumericEditField
        WidthTab                       matlab.ui.container.Tab
        CalculateButtonWidth           matlab.ui.control.Button
        WidthLabel                     matlab.ui.control.Label
        mLabel_6                       matlab.ui.control.Label
        EnterpercentageofunprotectedareaEditFieldLabel_3  matlab.ui.control.Label
        PercentageWidth                matlab.ui.control.NumericEditField
        EnterheightLabel               matlab.ui.control.Label
        EnterHeightEditFieldWidth      matlab.ui.control.NumericEditField
        EnterseparationdistanceEditFieldLabel_2  matlab.ui.control.Label
        EnterseparationdistanceEditFieldWidth  matlab.ui.control.NumericEditField
        UnprotectedAreaTab             matlab.ui.container.Tab
        CalculateButtonUA              matlab.ui.control.Button
        PercentageofunprotectedareaLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        EnterheightLabel_2             matlab.ui.control.Label
        EnterHeightEditFieldUA         matlab.ui.control.NumericEditField
        EnterseparationdistanceEditFieldLabel_3  matlab.ui.control.Label
        EnterseparationdistanceEditFieldUA  matlab.ui.control.NumericEditField
        EnterwidthEditField_2Label_2   matlab.ui.control.Label
        EnterwidthEditFieldUA          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        radiationIntensity
        enclosingWidth
        enclosingHeight
        percentageUnprotected
        suppressionSystem
        separationDistanceEntered
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReducedButton
        function ReducedButtonPushed(app, event)
            app.radiationIntensity = 84;
            app.kWm2Label_2.FontWeight = 'bold';
            app.kWm2Label.FontWeight = 'normal';
        end

        % Button pushed function: NormalButton
        function NormalButtonPushed(app, event)
            app.radiationIntensity = 168;
            app.kWm2Label.FontWeight = 'bold';
            app.kWm2Label_2.FontWeight = 'normal';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.percentageUnprotected = app.Percentage.Value/100;
            app.enclosingWidth = app.EnterwidthEditField.Value;
            app.enclosingHeight = app.EnterHeightEditField.Value;
            recalculatedRadiationIntensity = app.radiationIntensity * app.percentageUnprotected;
            criticalRadiationIntensity = 12.6;
            targetViewFactor = criticalRadiationIntensity / recalculatedRadiationIntensity;
            separationResult = separationDistanceCalculation(app.enclosingWidth, app.enclosingHeight, targetViewFactor);
            if app.suppressionSystem == 1
                boundaryDistance = round(separationResult/4, 3);
                separationResult = round(separationResult/2, 3);
            else
                boundaryDistance = round(separationResult/2, 3);
            end
            app.mLabel_3.Text = strcat((num2str(boundaryDistance)),' m');
            app.mLabel_4.Text = strcat((num2str(separationResult)),' m');
        end

        % Value changed function: Percentage
        function PercentageValueChanged(app, event)
            app.percentageUnprotected = app.Percentage.Value/100;
        end

        % Value changed function: EnterwidthEditField
        function EnterwidthEditFieldValueChanged(app, event)
            app.enclosingWidth = app.EnterwidthEditField.Value;
        end

        % Value changed function: EnterHeightEditField
        function EnterHeightEditFieldValueChanged(app, event)
            app.enclosingHeight = app.EnterHeightEditField.Value;
        end

        % Value changed function: SprinklerCheckbox
        function SprinklerCheckboxValueChanged(app, event)
            app.suppressionSystem = app.SprinklerCheckbox.Value;
        end

        % Value changed function: EnterwidthEditFieldHeight
        function EnterwidthEditFieldHeightValueChanged(app, event)
            app.enclosingWidth = app.EnterwidthEditFieldHeight.Value;
        end

        % Value changed function: PercentageHeight
        function PercentageHeightValueChanged(app, event)
            app.percentageUnprotected = app.PercentageHeight.Value/100;
        end

        % Value changed function: 
        % EnterseparationdistanceEditFieldHeight
        function EnterseparationdistanceEditFieldHeightValueChanged(app, event)
            app.separationDistanceEntered = app.EnterseparationdistanceEditFieldHeight.Value;
        end

        % Button pushed function: CalculateButtonHeight
        function CalculateButtonHeightPushed(app, event)
            app.enclosingWidth = app.EnterwidthEditFieldHeight.Value;
            app.percentageUnprotected = app.PercentageHeight.Value/100;
            app.separationDistanceEntered = app.EnterseparationdistanceEditFieldHeight.Value;
            recalculatedRadiationIntensity = app.radiationIntensity * app.percentageUnprotected;
            criticalRadiationIntensity = 12.6;
            targetViewFactor = criticalRadiationIntensity / recalculatedRadiationIntensity;
            heightResult = heightCalculation(app.enclosingWidth, app.separationDistanceEntered, targetViewFactor);
            app.mLabel_5.Text = strcat((num2str(heightResult)),' m');
        end

        % Value changed function: EnterHeightEditFieldWidth
        function EnterHeightEditFieldWidthValueChanged(app, event)
            app.enclosingHeight = app.EnterHeightEditFieldWidth.Value;
        end

        % Value changed function: 
        % EnterseparationdistanceEditFieldWidth
        function EnterseparationdistanceEditFieldWidthValueChanged(app, event)
            app.separationDistanceEntered = app.EnterseparationdistanceEditFieldWidth.Value;
        end

        % Value changed function: PercentageWidth
        function PercentageWidthValueChanged(app, event)
            app.percentageUnprotected = app.PercentageWidth.Value/100;
        end

        % Button pushed function: CalculateButtonWidth
        function CalculateButtonWidthPushed(app, event)
            app.enclosingHeight = app.EnterHeightEditFieldWidth.Value;
            app.percentageUnprotected = app.PercentageWidth.Value/100;
            app.separationDistanceEntered = app.EnterseparationdistanceEditFieldWidth.Value;
            recalculatedRadiationIntensity = app.radiationIntensity * app.percentageUnprotected;
            criticalRadiationIntensity = 12.6;
            targetViewFactor = criticalRadiationIntensity / recalculatedRadiationIntensity;
            widthResult = widthCalculation(app.enclosingHeight, app.separationDistanceEntered, targetViewFactor);
            app.mLabel_6.Text = strcat((num2str(widthResult)),' m');
        end

        % Button pushed function: CalculateButtonUA
        function CalculateButtonUAPushed(app, event)
            app.enclosingHeight = app.EnterHeightEditFieldUA.Value;
            app.enclosingWidth = app.EnterwidthEditFieldUA.Value;
            app.separationDistanceEntered = app.EnterseparationdistanceEditFieldUA.Value;           
            app.percentageUnprotected = app.PercentageWidth.Value/100;
            unprotectedAreaResult = unprotectedAreaCalculation(app.enclosingHeight, app.enclosingWidth, app.separationDistanceEntered, app.radiationIntensity);
            app.Label.Text = strcat((num2str(unprotectedAreaResult)),' %');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BoundaryDistanceCalculatorLabel = uilabel(app.UIFigure);
            app.BoundaryDistanceCalculatorLabel.Text = 'Boundary Distance Calculator';
            app.RadiationIntensityButtonGroup = uibuttongroup(app.UIFigure);
            app.RadiationIntensityButtonGroup.Title = 'Radiation Intensity';
            app.kWm2Label = uilabel(app.RadiationIntensityButtonGroup);
            app.kWm2Label.Text = '168 kW/m^2';
            app.kWm2Label_2 = uilabel(app.RadiationIntensityButtonGroup);
            app.kWm2Label_2.Text = '84 kW/m^2';
            app.NormalButton = uibutton(app.RadiationIntensityButtonGroup, 'push');
            app.NormalButton.ButtonPushedFcn = createCallbackFcn(app, @NormalButtonPushed, true);
            app.NormalButton.Text = 'Normal';
            app.ReducedButton = uibutton(app.RadiationIntensityButtonGroup, 'push');
            app.ReducedButton.ButtonPushedFcn = createCallbackFcn(app, @ReducedButtonPushed, true);
            app.ReducedButton.Text = 'Reduced';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BoundaryDistanceTab = uitab(app.TabGroup);
            app.BoundaryDistanceTab.Title = 'Boundary Distance';
            app.SprinklerCheckbox = uicheckbox(app.BoundaryDistanceTab);
            app.SprinklerCheckbox.ValueChangedFcn = createCallbackFcn(app, @SprinklerCheckboxValueChanged, true);
            app.SprinklerCheckbox.Text = '';
            app.SprinklerSuppressionSystemLabel = uilabel(app.BoundaryDistanceTab);
            app.SprinklerSuppressionSystemLabel.Text = 'Sprinkler Suppression System:';
            app.EnterpercentageofunprotectedareaEditFieldLabel = uilabel(app.BoundaryDistanceTab);
            app.EnterpercentageofunprotectedareaEditFieldLabel.Text = 'Enter percentage of unprotected area:';
            app.Percentage = uieditfield(app.BoundaryDistanceTab, 'numeric');
            app.Percentage.Limits = [0 100];
            app.Percentage.RoundFractionalValues = 'on';
            app.Percentage.ValueChangedFcn = createCallbackFcn(app, @PercentageValueChanged, true);
            app.EnterwidthEditFieldLabel = uilabel(app.BoundaryDistanceTab);
            app.EnterwidthEditFieldLabel.Text = 'Enter width:';
            app.EnterwidthEditField = uieditfield(app.BoundaryDistanceTab, 'numeric');
            app.EnterwidthEditField.ValueChangedFcn = createCallbackFcn(app, @EnterwidthEditFieldValueChanged, true);
            app.EnterHeightLabel = uilabel(app.BoundaryDistanceTab);
            app.EnterHeightLabel.Text = 'Enter Height:';
            app.EnterHeightEditField = uieditfield(app.BoundaryDistanceTab, 'numeric');
            app.EnterHeightEditField.ValueChangedFcn = createCallbackFcn(app, @EnterHeightEditFieldValueChanged, true);
            app.CalculateButton = uibutton(app.BoundaryDistanceTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SeparationDistanceLabel = uilabel(app.BoundaryDistanceTab);
            app.SeparationDistanceLabel.Text = 'Separation Distance:';
            app.BoundaryDistanceLabel_2 = uilabel(app.BoundaryDistanceTab);
            app.BoundaryDistanceLabel_2.Text = 'Boundary Distance:';
            app.mLabel_4 = uilabel(app.BoundaryDistanceTab);
            app.mLabel_4.Text = '0.000 m';
            app.mLabel_3 = uilabel(app.BoundaryDistanceTab);
            app.mLabel_3.Text = '0.000 m';
            app.HeightTab = uitab(app.TabGroup);
            app.HeightTab.Title = 'Height';
            app.CalculateButtonHeight = uibutton(app.HeightTab, 'push');
            app.CalculateButtonHeight.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonHeightPushed, true);
            app.CalculateButtonHeight.Text = 'Calculate';
            app.HeightLabel = uilabel(app.HeightTab);
            app.HeightLabel.Text = 'Height:';
            app.mLabel_5 = uilabel(app.HeightTab);
            app.mLabel_5.Text = '0.000 m';
            app.EnterpercentageofunprotectedareaEditFieldLabel_2 = uilabel(app.HeightTab);
            app.EnterpercentageofunprotectedareaEditFieldLabel_2.Text = 'Enter percentage of unprotected area:';
            app.PercentageHeight = uieditfield(app.HeightTab, 'numeric');
            app.PercentageHeight.Limits = [0 100];
            app.PercentageHeight.RoundFractionalValues = 'on';
            app.PercentageHeight.ValueChangedFcn = createCallbackFcn(app, @PercentageHeightValueChanged, true);
            app.EnterwidthEditField_2Label = uilabel(app.HeightTab);
            app.EnterwidthEditField_2Label.Text = 'Enter width:';
            app.EnterwidthEditFieldHeight = uieditfield(app.HeightTab, 'numeric');
            app.EnterwidthEditFieldHeight.ValueChangedFcn = createCallbackFcn(app, @EnterwidthEditFieldHeightValueChanged, true);
            app.EnterseparationdistanceEditFieldLabel = uilabel(app.HeightTab);
            app.EnterseparationdistanceEditFieldLabel.Text = 'Enter separation distance:';
            app.EnterseparationdistanceEditFieldHeight = uieditfield(app.HeightTab, 'numeric');
            app.EnterseparationdistanceEditFieldHeight.ValueChangedFcn = createCallbackFcn(app, @EnterseparationdistanceEditFieldHeightValueChanged, true);
            app.WidthTab = uitab(app.TabGroup);
            app.WidthTab.Title = 'Width';
            app.CalculateButtonWidth = uibutton(app.WidthTab, 'push');
            app.CalculateButtonWidth.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonWidthPushed, true);
            app.CalculateButtonWidth.Text = 'Calculate';
            app.WidthLabel = uilabel(app.WidthTab);
            app.WidthLabel.Text = 'Width:';
            app.mLabel_6 = uilabel(app.WidthTab);
            app.mLabel_6.Text = '0.000 m';
            app.EnterpercentageofunprotectedareaEditFieldLabel_3 = uilabel(app.WidthTab);
            app.EnterpercentageofunprotectedareaEditFieldLabel_3.Text = 'Enter percentage of unprotected area:';
            app.PercentageWidth = uieditfield(app.WidthTab, 'numeric');
            app.PercentageWidth.Limits = [0 100];
            app.PercentageWidth.RoundFractionalValues = 'on';
            app.PercentageWidth.ValueChangedFcn = createCallbackFcn(app, @PercentageWidthValueChanged, true);
            app.EnterheightLabel = uilabel(app.WidthTab);
            app.EnterheightLabel.Text = 'Enter height:';
            app.EnterHeightEditFieldWidth = uieditfield(app.WidthTab, 'numeric');
            app.EnterHeightEditFieldWidth.ValueChangedFcn = createCallbackFcn(app, @EnterHeightEditFieldWidthValueChanged, true);
            app.EnterseparationdistanceEditFieldLabel_2 = uilabel(app.WidthTab);
            app.EnterseparationdistanceEditFieldLabel_2.Text = 'Enter separation distance:';
            app.EnterseparationdistanceEditFieldWidth = uieditfield(app.WidthTab, 'numeric');
            app.EnterseparationdistanceEditFieldWidth.ValueChangedFcn = createCallbackFcn(app, @EnterseparationdistanceEditFieldWidthValueChanged, true);
            app.UnprotectedAreaTab = uitab(app.TabGroup);
            app.UnprotectedAreaTab.Title = 'Unprotected Area';
            app.CalculateButtonUA = uibutton(app.UnprotectedAreaTab, 'push');
            app.CalculateButtonUA.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonUAPushed, true);
            app.CalculateButtonUA.Text = 'Calculate';
            app.PercentageofunprotectedareaLabel = uilabel(app.UnprotectedAreaTab);
            app.PercentageofunprotectedareaLabel.Text = 'Percentage of unprotected area:';
            app.Label = uilabel(app.UnprotectedAreaTab);
            app.Label.Text = '0.000 %';
            app.EnterheightLabel_2 = uilabel(app.UnprotectedAreaTab);
            app.EnterheightLabel_2.Text = 'Enter height:';
            app.EnterHeightEditFieldUA = uieditfield(app.UnprotectedAreaTab, 'numeric');
            app.EnterseparationdistanceEditFieldLabel_3 = uilabel(app.UnprotectedAreaTab);
            app.EnterseparationdistanceEditFieldLabel_3.Text = 'Enter separation distance:';
            app.EnterseparationdistanceEditFieldUA = uieditfield(app.UnprotectedAreaTab, 'numeric');
            app.EnterwidthEditField_2Label_2 = uilabel(app.UnprotectedAreaTab);
            app.EnterwidthEditField_2Label_2.Text = 'Enter width:';
            app.EnterwidthEditFieldUA = uieditfield(app.UnprotectedAreaTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeparationDistanceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end